classdef swd_detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SWDs                    matlab.ui.Figure
        panFilter               matlab.ui.container.Panel
        HighpassHzLabel         matlab.ui.control.Label
        numHiPass               matlab.ui.control.NumericEditField
        LowpassHzLabel          matlab.ui.control.Label
        numLoPass               matlab.ui.control.NumericEditField
        AttenuationdBLabel      matlab.ui.control.Label
        num_dB                  matlab.ui.control.NumericEditField
        btnCalcFilter           matlab.ui.control.Button
        lampFilter              matlab.ui.control.Lamp
        OrderEditField_2Label   matlab.ui.control.Label
        numFilterOrder          matlab.ui.control.NumericEditField
        EpochsecLabel           matlab.ui.control.Label
        numEpochLen             matlab.ui.control.NumericEditField
        panPlotParams           matlab.ui.container.Panel
        btnRawYDown             matlab.ui.control.Button
        btnRawYUp               matlab.ui.control.Button
        RawYLimEditFieldLabel   matlab.ui.control.Label
        numRawYLim              matlab.ui.control.NumericEditField
        FiltYLimEditFieldLabel  matlab.ui.control.Label
        numFiltYLim             matlab.ui.control.NumericEditField
        btnFiltYUp              matlab.ui.control.Button
        btnFiltYDown            matlab.ui.control.Button
        swiHalfWave             matlab.ui.control.RockerSwitch
        sldCurrEpoch            matlab.ui.control.Slider
        spinCurrEpoch           matlab.ui.control.Spinner
        CurrentepochLabel       matlab.ui.control.Label
        plotRaw                 matlab.ui.control.UIAxes
        plotFilter              matlab.ui.control.UIAxes
        panEvents               matlab.ui.container.Panel
        listSavedSWDs           matlab.ui.control.ListBox
        EndLabel                matlab.ui.control.Label
        sldEndSWD               matlab.ui.control.Slider
        StartSliderLabel        matlab.ui.control.Label
        sldStartSWD             matlab.ui.control.Slider
        btnNewSWD               matlab.ui.control.Button
        btnSaveSWD              matlab.ui.control.Button
        btnDelSWD               matlab.ui.control.Button
        btnCancelSWD            matlab.ui.control.Button
        spinStartSWD            matlab.ui.control.Spinner
        spinEndSWD              matlab.ui.control.Spinner
        btnExport               matlab.ui.control.Button
        btnSpanBack             matlab.ui.control.Button
        btnSpanForward          matlab.ui.control.Button
        panFinder               matlab.ui.container.Panel
        MinpeaksLabel           matlab.ui.control.Label
        numMinPeaks             matlab.ui.control.NumericEditField
        MinpeakmsLabel          matlab.ui.control.Label
        numMinPeakDistance      matlab.ui.control.NumericEditField
        PeakrangemsLabel        matlab.ui.control.Label
        numPeakRange            matlab.ui.control.NumericEditField
        ZpointsEditFieldLabel   matlab.ui.control.Label
        numZPoints              matlab.ui.control.NumericEditField
        listFoundEvents         matlab.ui.control.ListBox
        btnFindEvents           matlab.ui.control.Button
        panSignal               matlab.ui.container.Panel
        lblHz                   matlab.ui.control.Label
        lblDuration             matlab.ui.control.Label
    end

    % some name prefixing conventions:
    % the: a property
    % fun: a function without a return value
    % ret: a function that returns a value
    % num: a numerical input box
    % sld: a slider
    % btn: a pushbutton
    % spin: a spinner
    properties (Access = private)
        theOriginalData % data vector, mandatory argument
        theSamplingRate % in Hz, mandatory argument
        theRawEEG       % the signal, after trimming to the closest epoch and multiplying
        theFiltEEG      % Description
        theMaxrEEG      % maximum raw EEG potential sample
        theMaxfEEG      % maximum filtered EEG potential sample
        theSD           % standard deviation of the raw signal
        theMU           % rectified filtered signal mean
        theSWDs         % the list of SWDs
        theStartLine    % for the currently defined SWD
        theEndLine
        thePX           % timing of found peaks
        thePY           % height of found peaks
        thePeakStreak   % boolean: whether the nth found event is part of a meaningful sequence
    end

    methods (Access = private)
   
        function funLoadDataFile(app)
            % to be fixed
            app.theOriginalData = load(app.lblDataFile.Text);
            % set things up after data loading
            app.funSetupSignals
        end
        
        function funPlotEEG(app)
            % plotting the raw signal
            rData = app.theRawEEG(app.retEpochIndices);
            plot(app.plotRaw, rData); 
            app.plotRaw.YLim = [-app.numRawYLim.Value app.numRawYLim.Value];
            
            % plotting filtered signal
            fData = app.theFiltEEG(app.retEpochIndices);            
            plot(app.plotFilter, fData);
            switch app.swiHalfWave.Value
                case 'Half'
                    t = 0;
                case 'Full'
                    t = -app.numFiltYLim.Value;
            end
            app.plotFilter.YLim = [t app.numFiltYLim.Value];
            line(app.plotFilter, ...
                [0 app.retSamplesPerEpoch()], ...
                [app.retThreshold app.retThreshold], ...
                'color', [1 .75 .25]);
            line(app.plotFilter, ...
                [0 app.retSamplesPerEpoch()], ...
                [-app.retThreshold -app.retThreshold], ...
                'color', [1 .75 .25]);
           
            n = app.theSamplingRate;
            m = app.numEpochLen.Value;
            set([app.plotRaw, app.plotFilter], ...
                'XLim', [1 app.retSamplesPerEpoch], ...
                'XTick', 0:n:m*n, ...
                'XTickLabel', 0:m, ...
                'YTickLabel', '', ...
                'YTick', '')

            % DRAWING EXISTING SWDs
            idx1 = app.theSWDs(:,1) >= app.retCurrFirstSample() & app.theSWDs(:,1) <= app.retCurrLastSample();
            idx2 = app.theSWDs(:,2) >= app.retCurrFirstSample() & app.theSWDs(:,2) <= app.retCurrLastSample();
            idx  = find(idx1 | idx2);
            
            if ~isempty(idx)
                for i=idx(:)'
                    t1 = max(app.theSWDs(i,1), app.retCurrFirstSample());
                    t2 = min(app.theSWDs(i,2), app.retCurrLastSample());
                    
                    x1 = app.retRelativeX(t1);
                    x2 = app.retRelativeX(t2);
                    
                    y = app.numRawYLim.Value;
                    patch(app.plotRaw, [x1 x2 x2 x1], [-y -y y y], [1 .9 .9]);
                end
                app.plotRaw.Children = flipud(app.plotRaw.Children);
            end
            
            % MARKING PEAKS
            hold(app.plotFilter, 'on');
            match = app.retCurrEventIdx(app.thePX) & app.thePeakStreak;
            if ~isempty(match)
                x = app.retRelativeX(app.thePX(match));
                y = app.thePY(match);
                plot(app.plotFilter, x, y, 'ro')
            end
            match = app.retCurrEventIdx(app.thePX) & ~app.thePeakStreak;
            if ~isempty(match)
                x = app.retRelativeX(app.thePX(match));
                y = app.thePY(match);
                plot(app.plotFilter, x, y, 'go')
            end
            hold(app.plotFilter, 'off');                
        end

        function funFindEvents(app)
            app.btnFindEvents.Enable = 'off';
            [y1,x1] = findpeaks(app.theFiltEEG, ...
                'MinPeakHeight', app.retThreshold(), ...
                'MinPeakDistance', app.retMinPeakDist());
            [y2,x2] = findpeaks(-app.theFiltEEG, ...
                'MinPeakHeight', app.retThreshold(), ...
                'MinPeakDistance', app.retMinPeakDist());
            
            % the above searches positive and negative peaks separately,
            % so events need to be time-sorted
            [x, i] = sort([x1;x2]);
            t = [y1; -y2];
            y = t(i);
            
            app.thePeakStreak = false(length(x), 1);
            
            % finding clusters of peaks
            for cp = 1:length(x)
                % events in cluster
                idx = find(x>=x(cp) & x<=x(cp)+ app.retPeakRange);
                if length(idx) >= app.numMinPeaks.Value
                    app.thePeakStreak(idx) = true;
                end
            end
            
            % This commented out code marks relevant epochs based on individual peaks
            % t = reshape(app.theFiltEEG, app.retSamplesPerEpoch, app.retTotEpochs);
            % ev = find(any(t > app.retThreshold));
            
            % timing of all the peaks that are part of a cluster
            t = x(app.thePeakStreak);
            ev = unique(floor(t/app.retSamplesPerEpoch)+1);
            
            app.listFoundEvents.Items     = string(ev);
            app.listFoundEvents.ItemsData = ev;
            app.listFoundEvents.Value     = {};

            app.thePX = x;
            app.thePY = y;
            
            app.funPlotEEG;            
        end

        function funCalcFilter(app)
            app.btnCalcFilter.Enable = 'off';
            h = fdesign.bandpass('N,Fst1,Fst2,Ast', ... 
                app.numFilterOrder.Value, ...
                app.numHiPass.Value, ...
                app.numLoPass.Value, ...
                app.num_dB.Value, ...
                app.theSamplingRate);
            app.theFiltEEG = filter(design(h, 'cheby2'), app.theRawEEG);
            
            app.theMU = mean(abs(app.theFiltEEG));
            app.theSD = std(abs(app.theFiltEEG));
            
            app.numFiltYLim.Value = max(abs(app.theFiltEEG));
            app.lampFilter.Color = 'g';
            app.funPlotEEG
        end
        
        function funEnableFilterGeneration(app)
            app.btnCalcFilter.Enable = 'on';
            app.lampFilter.Color = 'r';            
        end
        
        function funUpdateCurrentEpoch(app, val)
            if ~isempty(val)
                app.sldCurrEpoch.Value = val;
                app.spinCurrEpoch.Value = val;
                app.funPlotEEG()
            end
        end
        
        % ADJUSTING THE PLOTS' YLIMS        
        function funAdjustRawYLim(app, step)
            v = app.plotRaw.YLim * step;
            app.plotRaw.YLim = v;
            app.numRawYLim.Value = v(2);
        end
        function funAdjustFiltYLim(app, step)
            v = app.plotFilter.YLim * step;
            app.plotFilter.YLim = v;
            app.numFiltYLim.Value = v(2);
        end
        
        % Execute upon loading data and changing epoch duration or sampling rate
        function funSetupSignals(app)
            % how many epochs in the recording?
            % the total number of samples that can actually be used
            x = app.retTotEpochs() * app.retSamplesPerEpoch();
            % quite arbitrary multiplication to get more sensible Y values
            app.theRawEEG = app.theOriginalData(1:x);
            % the raw plot's YLim
            app.numRawYLim.Value = max(abs(app.theRawEEG));
            % reset epoch slider and spinner
            t = [app.spinCurrEpoch, app.sldCurrEpoch];
            set(t, 'Enable', 'on', 'Value', 1)
            app.sldCurrEpoch.Limits  = [1 app.retTotEpochs];
            % set limits for the SWD spinners and sliders
            t = [app.spinStartSWD, app.spinEndSWD, app.sldStartSWD, app.sldEndSWD];
            set(t, 'Limits', [1 app.retSamplesPerEpoch()])
            app.sldStartSWD.MajorTicksMode = 'auto';
            app.sldEndSWD.MajorTicksMode = 'auto';
            % resetting events
            set(app.listFoundEvents, 'items', {}, 'itemsdata', [], 'value', {})
            % generating filtered data
            app.funCalcFilter()            
        end
        
        function funSetBrowse(app, status)
            t = [
                app.sldCurrEpoch
                app.spinCurrEpoch
                app.btnCalcFilter
                app.btnNewSWD
                app.btnFindEvents
                app.listFoundEvents
                app.listSavedSWDs
                app.numZPoints
                app.btnDelSWD
                app.btnExport
                ];
            set(t, 'enable', app.retStatus(status));
            
            t = [
                app.btnCancelSWD
                app.sldStartSWD
                app.sldEndSWD
                app.spinStartSWD
                app.spinEndSWD
                app.btnSpanForward
                ];
            set(t, 'enable', app.retStatus(~status));
            if status
                delete([app.theStartLine app.theEndLine])
            end
        end

        function funUpdateStartSWD(app, val)
            cv = min(val, app.sldEndSWD.Value-1);
            app.theStartLine.XData = [cv cv];
            app.spinStartSWD.Value =  cv;
            app.sldStartSWD.Value  =  cv;
            app.theStartLine.XData = [cv cv];
            app.btnSaveSWD.Enable = 'on';
        end

        function funUpdateEndSWD(app, val)
            cv = max(val, app.sldStartSWD.Value+1);
            app.theEndLine.XData = [cv cv];
            app.spinEndSWD.Value =  cv;
            app.sldEndSWD.Value  =  cv;
            app.theEndLine.XData = [cv cv];
            app.btnSaveSWD.Enable = 'on';
        end

        % RETURNING COMPUTED VALUES
        function rv = retStatus(~, val),       if val, rv = 'on'; else, rv = 'off'; end,                          end
        function rv = retSamplesPerEpoch(app), rv = app.numEpochLen.Value * app.theSamplingRate;                  end
        function rv = retThreshold(app),       rv = app.numZPoints.Value * app.theSD + app.theMU;                 end
        function rv = retCurrEpoch(app),       rv = app.spinCurrEpoch.Value;                                      end
        function rv = retCurrFirstSample(app), rv = (app.retCurrEpoch()-1) * app.retSamplesPerEpoch() + 1;        end
        function rv = retCurrLastSample(app),  rv = app.retCurrEpoch() * app.retSamplesPerEpoch();                end
        function rv = retRelativeX(app, x),    rv = x - (app.retCurrEpoch()-1)*app.retSamplesPerEpoch();          end
        function rv = retEpochIndices(app),    rv = app.retCurrFirstSample():app.retCurrLastSample();             end
        function rv = retCurrEventIdx(app, x), rv = x >= app.retCurrFirstSample() & x <= app.retCurrLastSample(); end
        function rv = retMinPeakDist(app),     rv = app.numMinPeakDistance.Value/1000*app.theSamplingRate;        end
        function rv = retTotEpochs(app),       rv = floor(length(app.theOriginalData)/app.retSamplesPerEpoch());  end
        function rv = retPeakRange(app),       rv = (app.numPeakRange.Value/1000) * app.theSamplingRate;          end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEG, Hz, varargin)
            p = inputParser;
            isnv = @(x) isnumeric(x) & numel(x)==length(x);
            isns = @(x) isnumeric(x) & numel(x)==1;
            p.addRequired('EEG', isnv)
            p.addRequired('Hz',  isns)
            p.addParameter('Epoch', 10, isns)
            p.addParameter('Order',  4, isns)
            p.addParameter('High',   4, isns)
            p.addParameter('Low',   10, isns)
            p.addParameter('dB',    20, isns)
            p.addParameter('MinPks', 1, isns)
            p.addParameter('MinDPk',20, isns)
            p.addParameter('MaxDPk',90, isns)
            p.addParameter('Z',      3, isns)
            
            app.theOriginalData =  EEG;
            app.theSamplingRate = Hz;
            % set up current signal info
            d = length(EEG)/Hz/86400;
            app.lblDuration.Text = ['Duration: ' datestr(d,'DD HH:MM:SS')];
            app.lblHz.Text = sprintf('Hz: %d', Hz);
            % set things up after data loading
            app.theSWDs = zeros(0,2);
            app.listSavedSWDs.ItemsData = {};
            app.funSetupSignals
        end

        % Callback function
        function btnPickFilePushed(app, event)
            filename = uigetfile('*.mat','Select a data file');
            if ~isempty(filename)
                app.lblDataFile.Text = filename;
                funLoadDataFile(app)
            end
        end

        % Value changed function: spinCurrEpoch
        function spinCurrEpochValueChanged(app, event)
            val = max(app.spinCurrEpoch.Value, 1);
            val = min(val, app.retTotEpochs);
            app.funUpdateCurrentEpoch(val)
        end

        % Value changed function: numEpochLen
        function numEpochLenValueChanged(app, event)
            app.funSetupSignals
            app.funPlotEEG
        end

        % Button pushed function: btnCalcFilter
        function btnCalcFilterPushed(app, event)
            app.funCalcFilter
        end

        % Callback function
        function numHzValueChanged(app, event)
            app.funEnableFilterGeneration
            
        end

        % Value changed function: numFilterOrder
        function numFilterOrderValueChanged(app, event)
            app.numFilterOrder.Value = floor(app.numFilterOrder.Value/2)*2;
            app.funEnableFilterGeneration
        end

        % Value changed function: numHiPass
        function numHiPassValueChanged(app, event)
            app.funEnableFilterGeneration            
        end

        % Value changed function: numLoPass
        function numLoPassValueChanged(app, event)
            app.funEnableFilterGeneration
        end

        % Value changed function: num_dB
        function num_dBValueChanged(app, event)
            app.funEnableFilterGeneration
        end

        % Value changed function: sldCurrEpoch
        function sldCurrEpochValueChanged(app, event)
            app.funPlotEEG;
        end

        % Callback function
        function btnLoadPushed(app, event)
            app.lblDataFile.Text = 'test_sleeper.mat';
            funLoadDataFile(app);
        end

        % Value changing function: sldCurrEpoch
        function sldCurrEpochValueChanging(app, event)
            app.spinCurrEpoch.Value = floor(event.Value);
        end

        % Callback function
        function numRawYLimValueChanged(app, event)
            value = app.numRawYLim.Value;
            app.plotRaw.YLim = [-value value];
        end

        % Button pushed function: btnRawYDown
        function btnRawYDownPushed(app, event)
            app.funAdjustRawYLim(1.1);
        end

        % Button pushed function: btnRawYUp
        function btnRawYUpPushed(app, event)
            app.funAdjustRawYLim(.9);
        end

        % Button pushed function: btnFiltYUp
        function btnFiltYUpPushed(app, event)
            app.funAdjustFiltYLim(.9);            
        end

        % Button pushed function: btnFiltYDown
        function btnFiltYDownPushed(app, event)
            app.funAdjustFiltYLim(1.1);
        end

        % Value changed function: swiHalfWave
        function swiHalfWaveValueChanged(app, event)
            app.funPlotEEG            
        end

        % Callback function
        function numSDthreshValueChanged(app, event)
            app.funPlotEEG
        end

        % Button pushed function: btnFindEvents
        function btnFindEventsPushed(app, event)
            app.funFindEvents
        end

        % Value changed function: listFoundEvents
        function listFoundEventsValueChanged(app, event)
            value = app.listFoundEvents.Value;
            app.funUpdateCurrentEpoch(value);   
        end

        % Button pushed function: btnNewSWD
        function btnNewSWDPushed(app, event)
            app.funSetBrowse(false);
            t = app.retSamplesPerEpoch/2;            
            x = [app.spinStartSWD, app.spinEndSWD, app.sldStartSWD, app.sldEndSWD];
            set(x, 'enable', 'on', 'value', t)
            app.theEndLine   = line(app.plotRaw, [t t], app.plotRaw.YLim, 'color', [.7 0 0]);
            app.theStartLine = line(app.plotRaw, [t t], app.plotRaw.YLim, 'color', [0 .5 0]);
        end

        % Value changing function: sldStartSWD
        function sldStartSWDValueChanging(app, event)
            app.funUpdateStartSWD(event.Value)
        end

        % Button pushed function: btnSaveSWD
        function btnSaveSWDPushed(app, event)
            app.funSetBrowse(true)
            its = length(app.listSavedSWDs.Items)+1;
            abstart = app.retCurrFirstSample() + app.sldStartSWD.Value;
            absend  = app.retCurrFirstSample() + app.sldEndSWD.Value;
            app.theSWDs(its, 1:2) = [abstart, absend];
            app.listSavedSWDs.Items(its) = {num2str(its)};
            app.funPlotEEG();
            app.btnSaveSWD.Enable = 'off';
        end

        % Button pushed function: btnDelSWD
        function btnDelSWDPushed(app, event)
            v = app.listSavedSWDs.Value;
            if ~isempty(v)
                x = str2double(v);
                app.theSWDs(x, :) = [];
                app.listSavedSWDs.Items(x) = [];
                evs = 1:size(app.theSWDs, 1);
                app.listSavedSWDs.Items = cellstr(num2str(evs'));
                app.listSavedSWDs.Value = {};
                app.funPlotEEG
            end
        end

        % Value changing function: sldEndSWD
        function sldEndSWDValueChanging(app, event)
            app.funUpdateEndSWD(event.Value)
        end

        % Button pushed function: btnCancelSWD
        function btnCancelSWDPushed(app, event)
            app.funSetBrowse(true)
            app.btnSaveSWD.Enable = 'off';
        end

        % Value changed function: listSavedSWDs
        function listSavedSWDsValueChanged(app, event)
            val = str2double(event.Value);
            epoch = floor(app.theSWDs(val, 1)/app.retSamplesPerEpoch())+1;
            app.funUpdateCurrentEpoch(epoch);
            app.btnDelSWD.Enable = 'on';
        end

        % Value changed function: spinStartSWD
        function spinStartSWDValueChanged(app, event)
            app.funUpdateStartSWD(event.Value)
        end

        % Value changed function: spinEndSWD
        function spinEndSWDValueChanged(app, event)
            app.funUpdateEndSWD(event.Value)
        end

        % Value changed function: sldStartSWD
        function sldStartSWDValueChanged(app, event)
            app.funUpdateStartSWD(event.Value)            
        end

        % Value changed function: sldEndSWD
        function sldEndSWDValueChanged(app, event)
            app.funUpdateEndSWD(event.Value)            
        end

        % Button pushed function: btnExport
        function btnExportButtonPushed(app, event)
            var = inputdlg('Base Workspace variable name', 'Exporting events', 1, {'SWDs'});
            if ~isempty(var)
                v = var{1};
                if isvarname(v)
                    assignin('base', v, app.theSWDs)
                else
                    warndlg('Not a valid variable name!', 'Heads up!')
                end
            end
        end

        % Button pushed function: btnSpanBack
        function btnSpanBackPushed(app, event)
            
        end

        % Button pushed function: btnSpanForward
        function btnSpanForwardPushed(app, event)
            
        end

        % Value changed function: numZPoints
        function numZPointsValueChanged(app, event)
            app.btnFindEvents.Enable  = 'on';
            app.funPlotEEG
        end

        % Value changed function: numMinPeaks
        function numMinPeaksValueChanged(app, event)
            app.btnFindEvents.Enable  = 'on';
        end

        % Value changed function: numMinPeakDistance
        function numMinPeakDistanceValueChanged(app, event)
            app.btnFindEvents.Enable  = 'on';
        end

        % Value changed function: numPeakRange
        function numPeakRangeValueChanged(app, event)
            app.btnFindEvents.Enable  = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SWDs = uifigure('Visible', 'off');
            app.SWDs.Name = 'UI Figure';
            app.panFilter = uipanel(app.SWDs);
            app.panFilter.Title = 'Filter';
            app.HighpassHzLabel = uilabel(app.panFilter);
            app.HighpassHzLabel.Text = 'High pass (Hz)';
            app.numHiPass = uieditfield(app.panFilter, 'numeric');
            app.numHiPass.ValueChangedFcn = createCallbackFcn(app, @numHiPassValueChanged, true);
            app.numHiPass.Value = 5;
            app.LowpassHzLabel = uilabel(app.panFilter);
            app.LowpassHzLabel.Text = 'Low pass (Hz)';
            app.numLoPass = uieditfield(app.panFilter, 'numeric');
            app.numLoPass.ValueChangedFcn = createCallbackFcn(app, @numLoPassValueChanged, true);
            app.numLoPass.Value = 8;
            app.AttenuationdBLabel = uilabel(app.panFilter);
            app.AttenuationdBLabel.Text = 'Attenuation (dB)';
            app.num_dB = uieditfield(app.panFilter, 'numeric');
            app.num_dB.ValueChangedFcn = createCallbackFcn(app, @num_dBValueChanged, true);
            app.num_dB.Value = 30;
            app.btnCalcFilter = uibutton(app.panFilter, 'push');
            app.btnCalcFilter.ButtonPushedFcn = createCallbackFcn(app, @btnCalcFilterPushed, true);
            app.btnCalcFilter.Text = 'Recalculate';
            app.lampFilter = uilamp(app.panFilter);
            app.OrderEditField_2Label = uilabel(app.panFilter);
            app.OrderEditField_2Label.Text = 'Order';
            app.numFilterOrder = uieditfield(app.panFilter, 'numeric');
            app.numFilterOrder.Limits = [2 40];
            app.numFilterOrder.ValueDisplayFormat = '%.0f';
            app.numFilterOrder.ValueChangedFcn = createCallbackFcn(app, @numFilterOrderValueChanged, true);
            app.numFilterOrder.Value = 4;
            app.EpochsecLabel = uilabel(app.panFilter);
            app.EpochsecLabel.Text = 'Epoch (sec)';
            app.numEpochLen = uieditfield(app.panFilter, 'numeric');
            app.numEpochLen.ValueDisplayFormat = '%.0f';
            app.numEpochLen.ValueChangedFcn = createCallbackFcn(app, @numEpochLenValueChanged, true);
            app.numEpochLen.Value = 10;
            app.panPlotParams = uipanel(app.SWDs);
            app.panPlotParams.Title = 'Browse and View';
            app.btnRawYDown = uibutton(app.panPlotParams, 'push');
            app.btnRawYDown.ButtonPushedFcn = createCallbackFcn(app, @btnRawYDownPushed, true);
            app.btnRawYDown.Text = '-';
            app.btnRawYUp = uibutton(app.panPlotParams, 'push');
            app.btnRawYUp.ButtonPushedFcn = createCallbackFcn(app, @btnRawYUpPushed, true);
            app.btnRawYUp.Text = '+';
            app.RawYLimEditFieldLabel = uilabel(app.panPlotParams);
            app.RawYLimEditFieldLabel.Text = 'Raw YLim';
            app.numRawYLim = uieditfield(app.panPlotParams, 'numeric');
            app.FiltYLimEditFieldLabel = uilabel(app.panPlotParams);
            app.FiltYLimEditFieldLabel.Text = 'Filt YLim';
            app.numFiltYLim = uieditfield(app.panPlotParams, 'numeric');
            app.btnFiltYUp = uibutton(app.panPlotParams, 'push');
            app.btnFiltYUp.ButtonPushedFcn = createCallbackFcn(app, @btnFiltYUpPushed, true);
            app.btnFiltYUp.Text = '+';
            app.btnFiltYDown = uibutton(app.panPlotParams, 'push');
            app.btnFiltYDown.ButtonPushedFcn = createCallbackFcn(app, @btnFiltYDownPushed, true);
            app.btnFiltYDown.Text = '-';
            app.swiHalfWave = uiswitch(app.panPlotParams, 'rocker');
            app.swiHalfWave.Items = {'Full', 'Half'};
            app.swiHalfWave.ValueChangedFcn = createCallbackFcn(app, @swiHalfWaveValueChanged, true);
            app.swiHalfWave.Value = 'Full';
            app.sldCurrEpoch = uislider(app.panPlotParams);
            app.sldCurrEpoch.Limits = [1 100];
            app.sldCurrEpoch.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.sldCurrEpoch.ValueChangedFcn = createCallbackFcn(app, @sldCurrEpochValueChanged, true);
            app.sldCurrEpoch.ValueChangingFcn = createCallbackFcn(app, @sldCurrEpochValueChanging, true);
            app.sldCurrEpoch.Enable = 'off';
            app.sldCurrEpoch.Value = 1;
            app.spinCurrEpoch = uispinner(app.panPlotParams);
            app.spinCurrEpoch.Limits = [1 Inf];
            app.spinCurrEpoch.ValueDisplayFormat = '%.0f';
            app.spinCurrEpoch.ValueChangedFcn = createCallbackFcn(app, @spinCurrEpochValueChanged, true);
            app.spinCurrEpoch.Enable = 'off';
            app.spinCurrEpoch.Value = 1;
            app.CurrentepochLabel = uilabel(app.panPlotParams);
            app.CurrentepochLabel.Text = 'Current epoch:';
            app.plotRaw = uiaxes(app.SWDs);
            app.plotFilter = uiaxes(app.SWDs);
            app.panEvents = uipanel(app.SWDs);
            app.panEvents.Title = 'Saving events';
            app.listSavedSWDs = uilistbox(app.panEvents);
            app.listSavedSWDs.Items = {};
            app.listSavedSWDs.ValueChangedFcn = createCallbackFcn(app, @listSavedSWDsValueChanged, true);
            app.listSavedSWDs.Value = {};
            app.EndLabel = uilabel(app.panEvents);
            app.EndLabel.Text = 'End';
            app.sldEndSWD = uislider(app.panEvents);
            app.sldEndSWD.MajorTicks = [0 20 40 60 80 100];
            app.sldEndSWD.MajorTickLabels = {'', '', '', '', '', ''};
            app.sldEndSWD.ValueChangedFcn = createCallbackFcn(app, @sldEndSWDValueChanged, true);
            app.sldEndSWD.ValueChangingFcn = createCallbackFcn(app, @sldEndSWDValueChanging, true);
            app.sldEndSWD.MinorTicks = [];
            app.sldEndSWD.Enable = 'off';
            app.StartSliderLabel = uilabel(app.panEvents);
            app.StartSliderLabel.Text = 'Start';
            app.sldStartSWD = uislider(app.panEvents);
            app.sldStartSWD.MajorTicks = [0 20 40 60 80 100];
            app.sldStartSWD.MajorTickLabels = {'', '', '', '', '', ''};
            app.sldStartSWD.ValueChangedFcn = createCallbackFcn(app, @sldStartSWDValueChanged, true);
            app.sldStartSWD.ValueChangingFcn = createCallbackFcn(app, @sldStartSWDValueChanging, true);
            app.sldStartSWD.MinorTicks = [];
            app.sldStartSWD.Enable = 'off';
            app.btnNewSWD = uibutton(app.panEvents, 'push');
            app.btnNewSWD.ButtonPushedFcn = createCallbackFcn(app, @btnNewSWDPushed, true);
            app.btnNewSWD.Text = 'New';
            app.btnSaveSWD = uibutton(app.panEvents, 'push');
            app.btnSaveSWD.ButtonPushedFcn = createCallbackFcn(app, @btnSaveSWDPushed, true);
            app.btnSaveSWD.Enable = 'off';
            app.btnSaveSWD.Text = 'Save';
            app.btnDelSWD = uibutton(app.panEvents, 'push');
            app.btnDelSWD.ButtonPushedFcn = createCallbackFcn(app, @btnDelSWDPushed, true);
            app.btnDelSWD.Text = 'Delete selected';
            app.btnCancelSWD = uibutton(app.panEvents, 'push');
            app.btnCancelSWD.ButtonPushedFcn = createCallbackFcn(app, @btnCancelSWDPushed, true);
            app.btnCancelSWD.Enable = 'off';
            app.btnCancelSWD.Text = 'Cancel';
            app.spinStartSWD = uispinner(app.panEvents);
            app.spinStartSWD.RoundFractionalValues = 'on';
            app.spinStartSWD.ValueDisplayFormat = '%.0f';
            app.spinStartSWD.ValueChangedFcn = createCallbackFcn(app, @spinStartSWDValueChanged, true);
            app.spinStartSWD.Enable = 'off';
            app.spinEndSWD = uispinner(app.panEvents);
            app.spinEndSWD.RoundFractionalValues = 'on';
            app.spinEndSWD.ValueDisplayFormat = '%.0f';
            app.spinEndSWD.ValueChangedFcn = createCallbackFcn(app, @spinEndSWDValueChanged, true);
            app.spinEndSWD.Enable = 'off';
            app.btnExport = uibutton(app.panEvents, 'push');
            app.btnExport.ButtonPushedFcn = createCallbackFcn(app, @btnExportButtonPushed, true);
            app.btnExport.Text = 'Export';
            app.btnSpanBack = uibutton(app.panEvents, 'push');
            app.btnSpanBack.ButtonPushedFcn = createCallbackFcn(app, @btnSpanBackPushed, true);
            app.btnSpanBack.Enable = 'off';
            app.btnSpanBack.Text = '<';
            app.btnSpanForward = uibutton(app.panEvents, 'push');
            app.btnSpanForward.ButtonPushedFcn = createCallbackFcn(app, @btnSpanForwardPushed, true);
            app.btnSpanForward.Enable = 'off';
            app.btnSpanForward.Text = '>';
            app.panFinder = uipanel(app.SWDs);
            app.panFinder.Title = 'Find events';
            app.MinpeaksLabel = uilabel(app.panFinder);
            app.MinpeaksLabel.Text = 'Min peaks';
            app.numMinPeaks = uieditfield(app.panFinder, 'numeric');
            app.numMinPeaks.Limits = [1 Inf];
            app.numMinPeaks.ValueDisplayFormat = '%.0f';
            app.numMinPeaks.ValueChangedFcn = createCallbackFcn(app, @numMinPeaksValueChanged, true);
            app.numMinPeaks.Value = 1;
            app.MinpeakmsLabel = uilabel(app.panFinder);
            app.MinpeakmsLabel.Text = 'Min ∆-peak (ms)';
            app.numMinPeakDistance = uieditfield(app.panFinder, 'numeric');
            app.numMinPeakDistance.Limits = [1 Inf];
            app.numMinPeakDistance.ValueDisplayFormat = '%.0f';
            app.numMinPeakDistance.ValueChangedFcn = createCallbackFcn(app, @numMinPeakDistanceValueChanged, true);
            app.numMinPeakDistance.Value = 20;
            app.PeakrangemsLabel = uilabel(app.panFinder);
            app.PeakrangemsLabel.Text = 'Peak range (ms)';
            app.numPeakRange = uieditfield(app.panFinder, 'numeric');
            app.numPeakRange.Limits = [1 Inf];
            app.numPeakRange.ValueDisplayFormat = '%.0f';
            app.numPeakRange.ValueChangedFcn = createCallbackFcn(app, @numPeakRangeValueChanged, true);
            app.numPeakRange.Value = 1000;
            app.ZpointsEditFieldLabel = uilabel(app.panFinder);
            app.ZpointsEditFieldLabel.Text = 'Z points';
            app.numZPoints = uieditfield(app.panFinder, 'numeric');
            app.numZPoints.ValueChangedFcn = createCallbackFcn(app, @numZPointsValueChanged, true);
            app.numZPoints.Value = 3;
            app.listFoundEvents = uilistbox(app.panFinder);
            app.listFoundEvents.Items = {};
            app.listFoundEvents.ValueChangedFcn = createCallbackFcn(app, @listFoundEventsValueChanged, true);
            app.listFoundEvents.Value = {};
            app.btnFindEvents = uibutton(app.panFinder, 'push');
            app.btnFindEvents.ButtonPushedFcn = createCallbackFcn(app, @btnFindEventsPushed, true);
            app.btnFindEvents.Text = 'Find';
            app.panSignal = uipanel(app.SWDs);
            app.panSignal.Title = 'Current signal';
            app.lblHz = uilabel(app.panSignal);
            app.lblHz.Text = 'Hz';
            app.lblDuration = uilabel(app.panSignal);
            app.lblDuration.Text = 'Duration';
            % Show the figure after all components are created
            app.SWDs.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swd_detect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SWDs)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SWDs)
        end
    end
end